classdef signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        filenameEditField       matlab.ui.control.EditField
        filenameEditFieldLabel  matlab.ui.control.Label
        importButton            matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            [filename, path] = uigetfile('*.wav', '*.mp3'); %https://www.mathworks.com/matlabcentral/answers/466061-plot-a-sound-file-in-matlab-gui
            app.filenameEditField.Value=strcat(path, filename);
            figure(app.UIFigure);
            [data, Fs] = audioread(strcat(path, filename));
            time = (0:size(data,1)-1)/Fs;
            plot(app.UIAxes, time, data);
            Ls = length(data)/Fs; % https://www.educba.com/matlab-fft/
            F = fft(data);% Calling fft() function for signal dfa
%             PS2 = abs(F/Ls);% Double sampling plot
%             PS1 = PS2(1:Ls/2+1);% Single sampling plot
%             PS1(2:end-1) = PS1(2:end-1);
%             f = Fs*(0:(Ls/2))/Ls;
            plot(app.UIAxes2, abs(F));
%             np = 2^nextpow2(Ls);
%             d = 2;
%             F = fft(data,np, d);
%             PS2 = abs(F/Ls);
%             PS1 = PS2(:,1:np/2+1);
%             PS1(:,2:end-1) = 2*PS1(:,2:end-1);
%             plot(app.UIAxes2, plot(0:(Fs/np):(Fs/2-Fs/np),PS1(0,1:np/2)));
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'Time(t)')
            ylabel(app.UIAxes, 'Data')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Frequency Domain')
            xlabel(app.UIAxes2, '(f)')
            ylabel(app.UIAxes2, '|PF(f)|')
            zlabel(app.UIAxes2, 'Z')
            app.importButton = uibutton(app.UIFigure, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end